classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ThisisanappleLabel           matlab.ui.control.Label
        OpenImageButton              matlab.ui.control.Button
        FruitRecognitionSystemLabel  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenImageButton
        function OpenImageButtonPushed(app, event)
            global image;
            [filename pathname] = uigetfile({'*.jpg'}, "Open Image");
            if not (isequal(filename, 0) || isequal(pathname, 0))
                fullpathname = strcat(pathname, filename);
                image = imread(fullpathname);
                app.UIAxes.Visible = 'on'
                imshow(image, 'parent', app.UIAxes);
                app.ThisisanappleLabel.Visible = 'on'
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.FruitRecognitionSystemLabel = uilabel(app.UIFigure);
            app.FruitRecognitionSystemLabel.Text = 'Fruit Recognition System';
            app.OpenImageButton = uibutton(app.UIFigure, 'push');
            app.OpenImageButton.ButtonPushedFcn = createCallbackFcn(app, @OpenImageButtonPushed, true);
            app.OpenImageButton.Text = 'Open Image';
            app.ThisisanappleLabel = uilabel(app.UIFigure);
            app.ThisisanappleLabel.Visible = 'off';
            app.ThisisanappleLabel.Text = 'This is an apple';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end